classdef appMaquina03CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        zfxyTab                    matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        Panel_3                    matlab.ui.container.Panel
        LabelHarmonica             matlab.ui.control.Label
        LabelEqLaplace             matlab.ui.control.Label
        LabelDParcial              matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        ExecutarButton             matlab.ui.control.Button
        yLabel                     matlab.ui.control.Label
        eC                         matlab.ui.control.NumericEditField
        cLabel                     matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        aEditFieldLabel_5          matlab.ui.control.Label
        eH2                        matlab.ui.control.NumericEditField
        aEditFieldLabel_4          matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        eD                         matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        xLabel                     matlab.ui.control.Label
        eB                         matlab.ui.control.NumericEditField
        aEditFieldLabel_3          matlab.ui.control.Label
        eH1                        matlab.ui.control.NumericEditField
        aEditFieldLabel_2          matlab.ui.control.Label
        eA                         matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        eFxy                       matlab.ui.control.EditField
        fxyEditFieldLabel          matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        yfxTab                     matlab.ui.container.Tab
        Panel_2                    matlab.ui.container.Panel
        DerivaoNumricaButtonGroup  matlab.ui.container.ButtonGroup
        Button3                    matlab.ui.control.RadioButton
        Button2                    matlab.ui.control.RadioButton
        DFProgressivasButton       matlab.ui.control.RadioButton
        ResetButton_2              matlab.ui.control.Button
        ExecutarButton_2           matlab.ui.control.Button
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        xLabel_2                   matlab.ui.control.Label
        eB_2                       matlab.ui.control.NumericEditField
        aEditFieldLabel_8          matlab.ui.control.Label
        eH1_2                      matlab.ui.control.NumericEditField
        aEditFieldLabel_7          matlab.ui.control.Label
        eA_2                       matlab.ui.control.NumericEditField
        aEditFieldLabel_6          matlab.ui.control.Label
        eFx_2                      matlab.ui.control.EditField
        fxLabel                    matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function [x,y,dydx] = NDerivacaoDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h;
            end
            dydx(n) = (y(n)-y(n-1))/h;
            %dydx(n) = dydx(n-1);
            
        end
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if simplify(diff(f,x,2)+diff(diff(f,y),y))==0
                YN = 1;
            else YN = 0;
            end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
            ExecutarButton_2Pushed(app, [])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            
            erroFxy = MException("MATLAB:executar:badFxy",...
                                 "Introduza uma função real em x e y");

            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end

                dfdxsym = diff(f,x);
                LatexDParcial = ['$',...
                   '\displaystyle \frac{\partial z}{\partial x}=',...
                   latex(dfdxsym),...
                   '$'];
                app.LabelDParcial.Text = LatexDParcial;

                LatexEqLaplace = ['$',...
                   '\displaystyle \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                   '$'];
                app.LabelEqLaplace.Text = LatexEqLaplace;

                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;
                    
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                %mesh(app.UIAxes,x,y,z)
                surf(app.UIAxes,x,y,z)
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx_2.Value;
            f =@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH1_2.Value; b = app.eB_2.Value;

            try
                dydxsym = diff(f,x);
                dfdx =@(x) eval(vectorize(char(dydxsym)));
                [x,y,dydxDFP] = NDerivacaoDFP(app,f,a,b,h);
                dydxExata = dfdx(x);
                erroDFP = abs(dydxExata-dydxDFP);
                A = [x.',y.',dydxExata.',dydxDFP.',erroDFP.'];
                plot(app.UIAxes_2,x,y,"-r*")
                hold(app.UIAxes_2,"on")
                plot(app.UIAxes_2,x,dydxExata,"-g+")
                plot(app.UIAxes_2,x,dydxDFP,"-bo")
                hold(app.UIAxes_2,"off")
                legendaGrafico = ["y=f(x)","dydxExata","dydxDFP"];
                legend(app.UIAxes_2,legendaGrafico)
                grid(app.UIAxes_2,"on")
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z = f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ';';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ';';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ';';
            app.aEditFieldLabel_4 = uilabel(app.Panel);
            app.aEditFieldLabel_4.Text = 'd';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ';';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.Text = 'Reset';
            app.LabelDParcial = uilabel(app.Panel);
            app.LabelDParcial.Interpreter = 'latex';
            app.LabelDParcial.Text = 'DParcial';
            app.Panel_3 = uipanel(app.Panel);
            app.LabelEqLaplace = uilabel(app.Panel_3);
            app.LabelEqLaplace.Interpreter = 'latex';
            app.LabelEqLaplace.Text = 'EqLaplace';
            app.LabelHarmonica = uilabel(app.Panel_3);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y = f(x)';
            app.UIAxes_2 = uiaxes(app.yfxTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.fxLabel = uilabel(app.Panel_2);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx_2 = uieditfield(app.Panel_2, 'text');
            app.eFx_2.Value = 'sin(x)';
            app.aEditFieldLabel_6 = uilabel(app.Panel_2);
            app.aEditFieldLabel_6.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.eA_2.Value = -6;
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'h1';
            app.eH1_2 = uieditfield(app.Panel_2, 'numeric');
            app.eH1_2.Value = 0.2;
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 6;
            app.xLabel_2 = uilabel(app.Panel_2);
            app.xLabel_2.Text = 'x =';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ';';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ';';
            app.ExecutarButton_2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.ResetButton_2 = uibutton(app.Panel_2, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.DerivaoNumricaButtonGroup = uibuttongroup(app.Panel_2);
            app.DerivaoNumricaButtonGroup.Title = 'Derivação Numérica';
            app.DFProgressivasButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.DFProgressivasButton.Text = 'DFProgressivas';
            app.DFProgressivasButton.Value = true;
            app.Button2 = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina03CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end